classdef cache_monitor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Menu_3               matlab.ui.container.Menu
        Menu2_2              matlab.ui.container.Menu
        Image                matlab.ui.control.Image
        cacheLabel           matlab.ui.control.Label
        botton_target_rate   matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
        cacheEditFieldLabel  matlab.ui.control.Label
        cache_size           matlab.ui.control.NumericEditField
        EditFieldLabel       matlab.ui.control.Label
        block_size           matlab.ui.control.NumericEditField
        EditField_2Label     matlab.ui.control.Label
        connection           matlab.ui.control.NumericEditField
        EditField_3Label     matlab.ui.control.Label
        strategy             matlab.ui.control.NumericEditField
        tipsTextAreaLabel    matlab.ui.control.Label
        tipsTextArea         matlab.ui.control.TextArea
        Label                matlab.ui.control.Label
        result               matlab.ui.control.NumericEditField
        Label_2              matlab.ui.control.Label
        temp1                matlab.ui.control.EditField
        Label_3              matlab.ui.control.Label
        temp2                matlab.ui.control.EditField
        ButtonGroup          matlab.ui.container.ButtonGroup
        b1                   matlab.ui.control.RadioButton
        b2                   matlab.ui.control.RadioButton
        b3                   matlab.ui.control.RadioButton
        b4                   matlab.ui.control.RadioButton
        b2f                  matlab.ui.control.Button
        Image2               matlab.ui.control.Image
        Image4               matlab.ui.control.Image
        ContextMenu          matlab.ui.container.ContextMenu
        Menu_4               matlab.ui.container.Menu
        Menu2_3              matlab.ui.container.Menu
        Image3               matlab.ui.control.Image
    end

    
    properties (Access = public)
        m
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % myButtonPushed(app, event)  
            function myButtonPushed(app, event)
                % 创建一个新的窗口
                newFig = uifigure('Name', 'New Window');
                % 创建一个选项卡组
                tabGroup = uitabgroup(newFig);
                % 创建一个选项卡
                tab1 = uitab(tabGroup, 'Title', 'Tab 1');
                % 在选项卡中添加一个文本标签
                uilabel(tab1, 'Text', 'your input(n) is error!');
            end
        end

        % Button pushed function: botton_target_rate
        function botton_target_ratePushed(app, event)

            
          % myButtonPushed(app, event)  
            function myButtonPushed()
                % 创建一个新的窗口
                newFig = uifigure('Name', 'error');
                % 创建一个选项卡组
                tabGroup = uitabgroup(newFig);
                % 创建一个选项卡
                
                tab1 = uitab(tabGroup, 'Title', 'error');
                tab2 = uitab(tabGroup,'Title','data')
                % 在选项卡中添加一个文本标签
                Text = 'your input(n) is error!';
                Tooltip = 'Complete long text';
                uilabel(tab1,'Text', 'n');
                uilabel(tab2,'Text','cache');
            end
            
            if(0 ~= 0)
                a = app.m
            else
            a = textread('trace.txt','%s');
            end
            % a = cell2mat(a);
            a = upper(a);
            
            if(app.connection.Value<=0 || app.strategy.Value<=0 ||  app.strategy.Value>3 || app.cache_size.Value <= 0 || app.block_size.Value<=0) 
                myButtonPushed();
                % waitfor();
            else
                result = cache_hit_rate_nway1(a, app.cache_size.Value, app.block_size.Value, app.connection.Value, app.strategy.Value);
                app.result.Value = result;
                b =length(a)*result;
                % 参数输出
                app.temp1.Value = num2str(length(a));
                app.temp2.Value =num2str(b);
            end
            
            function hit_rate = cache_hit_rate_nway1(addresses, cache_size, block_size, n, choice)
                % addresses: 包含地址序列的列向量（十进制）
                % cache_size: 缓存大小（字节）
                % block_size: 块大小（字节）
                % n: 组相联度
               
                
                if iscell(addresses)
                    addresses = hex2dec(addresses);
                end
                % 计算块数、索引位数和组数
                num_blocks = cache_size / block_size;
                index_bits = log2(num_blocks / n);
                num_sets = num_blocks / n;
                
                
                lru = ones(num_sets, n);
                fifo = ones(num_sets, n);
                
                % 初始化缓存器
                cache = zeros(num_sets, n);
                
                % 初始化命中计数器
                hits = 0;
                
                % 模拟缓存器访问
                for i = 1:length(addresses)
                    address = addresses(i);
                    
                    % 计算组索引和块索引
                    set_index = mod(floor(address / block_size), num_sets) + 1;
                    block_index = floor(address / (block_size * num_sets));
                    
                    % 检查缓存器是否命中
                    hit = false;
                    for j = 1:n
                        if cache(set_index, j) == block_index
                            hits = hits + 1;
                            hit = true;
                            break;
                        end
                    end
                    % 随机替换策略
                    % 如果未命中，则将当前块存入缓存器
                    if ~hit && (choice == 1)
                        % 随机选择一个位置存储当前块
                        index = randi(n);
                        cache(set_index, index) = block_index;
                    end
                    
                    if ~hit && (choice == 2)
                        % 找到 LRU 位置存储当前块
                        [~, index] = max(lru(set_index, :));
                        cache(set_index, index) = block_index;
                        lru(set_index, index) = 0;
                        for k = 1:n
                            if k ~= index && cache(set_index, k) > 0
                                lru(set_index, k) = lru(set_index, k) + 1;
                            end
                        end
                    end
                    
                    if ~hit && (choice == 3)
                        % 找到 FIFO 队列最老的位置存储当前块
                        index = fifo(set_index);
                        cache(set_index, index) = block_index;
                        fifo(set_index) = mod(index, n) + 1;
                    end
                end
                
                % 计算命中率
                hit_rate = hits / length(addresses);
                 

            end
            
        end

        % Value changed function: cache_size
        function cache_sizeValueChanged(app, event)
            value = app.cache_size.Value;
            
        end

        % Callback function
        function botton_plotButtonPushed(app, event)
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            
            % 提供四种不同图象的输出
            % 关于cache容量变化的曲线，参数已经给定，利用相同的参数
            
            n = app.connection.Value;
            choice = app.strategy.Value;
            if(1 ~= 1)
                a = app.m;
            else
                a = textread('trace.txt','%s');
            end
            
            % a = cell2mat(a);
            a = upper(a);
            app.UIAxes.YLabel.String = "命中率";
            
            if(selectedButton == app.b1)
                result1 = cache_hit_rate_nway2(a, 1024, 16, 1, 1);
                result2 = cache_hit_rate_nway2(a, 1024*2, 16, 1, 1);
                result3 = cache_hit_rate_nway2(a, 1024*4, 16, 1, 1);
                result4 = cache_hit_rate_nway2(a, 1024*8, 16, 1, 1);
               
                app.UIAxes.XLabel.String = "cache容量";
                scatter(app.UIAxes,1,result1,"*");
                hold(app.UIAxes,"on");
                scatter(app.UIAxes,2,result2,"*");
                hold(app.UIAxes,"on");
                scatter(app.UIAxes,3,result3,"*");
                hold(app.UIAxes,"on");
                scatter(app.UIAxes,4,result4,"*");
                hold(app.UIAxes,"on");
                x= [1,2,3,4];
                y= [result1,result2,result3,result4];
                xx=linspace(1,4);
                yy=spline(x,y,xx);
                plot(app.UIAxes,xx,yy,'r',x,y,'o')
                hold(app.UIAxes,"off");
            end
            
            if(selectedButton == app.b2)
                result1 = cache_hit_rate_nway2(a, 1024*4, 16/4, 1, 1);
                result2 = cache_hit_rate_nway2(a, 1024*4, 16/2, 1, 1);
                result3 = cache_hit_rate_nway2(a, 1024*4, 16, 1, 1);
                result4 = cache_hit_rate_nway2(a, 1024*4, 16*2, 1, 1);
               
                app.UIAxes.XLabel.String = "块大小";
                scatter(app.UIAxes,1,result1,"*");
                hold(app.UIAxes,"on");
                scatter(app.UIAxes,2,result2,"*");
                hold(app.UIAxes,"on");
                scatter(app.UIAxes,3,result3,"*");
                hold(app.UIAxes,"on");
                scatter(app.UIAxes,4,result4,"*");
                hold(app.UIAxes,"on");
                x= [1,2,3,4];
                y= [result1,result2,result3,result4];
                xx=linspace(1,4);
                yy=spline(x,y,xx);
                plot(app.UIAxes,xx,yy,'r',x,y,'o')
                hold(app.UIAxes,"off");
            end
            
            if(selectedButton == app.b3)
                result1 = cache_hit_rate_nway2(a, 1024*4, 16, 1, 1);
                result2 = cache_hit_rate_nway2(a, 1024*4, 16, 1*2, 1);
                result3 = cache_hit_rate_nway2(a, 1024*4, 16, 1*4, 1);
                result4 = cache_hit_rate_nway2(a, 1024*4, 16, 1*8, 1);
               
                app.UIAxes.XLabel.String = "相连度"
                scatter(app.UIAxes,1,result1,"*");
                hold(app.UIAxes,"on");
                scatter(app.UIAxes,2,result2,"*");
                hold(app.UIAxes,"on");
                scatter(app.UIAxes,3,result3,"*");
                hold(app.UIAxes,"on");
                scatter(app.UIAxes,4,result4,"*");
                hold(app.UIAxes,"on");
                x= [1,2,3,4];
                y= [result1,result2,result3,result4];
                xx=linspace(1,4);
                yy=spline(x,y,xx);
                plot(app.UIAxes,xx,yy,'r',x,y,'o')
                hold(app.UIAxes,"off");
            end
            
            if(selectedButton == app.b4)
                result1 = cache_hit_rate_nway2(a, 1024*4, 16, 4, 1);
                result2 = cache_hit_rate_nway2(a, 1024*4, 16, 4, 2);
                result3 = cache_hit_rate_nway2(a, 1024*4, 16, 4, 3);
                
                app.UIAxes.XLabel.String = "替换策略";
                scatter(app.UIAxes,1,result1,"*");
                hold(app.UIAxes,"on");
                scatter(app.UIAxes,2,result2,"*");
                hold(app.UIAxes,"on");
                scatter(app.UIAxes,3,result3,"*");
                hold(app.UIAxes,"on");
                x= [1,2,3];
                y= [result1,result2,result3];
                xx=linspace(1,3);
                yy=spline(x,y,xx);
                plot(app.UIAxes,xx,yy,'r',x,y,'o')
                hold(app.UIAxes,"off");
                
            end
            
            
            function hit_rate = cache_hit_rate_nway2(addresses, cache_size, block_size, n, choice)
                % addresses: 包含地址序列的列向量（十进制）
                % cache_size: 缓存大小（字节）
                % block_size: 块大小（字节）
                % n: 组相联度
                if iscell(addresses)
                    addresses = hex2dec(addresses);
                end
                % 计算块数、索引位数和组数
                num_blocks = cache_size / block_size;
                index_bits = log2(num_blocks / n);
                num_sets = num_blocks / n;
                
                lru = ones(num_sets, n);
                fifo = ones(num_sets, n);
                
                % 初始化缓存器
                cache = zeros(num_sets, n);
                
                % 初始化命中计数器
                hits = 0;
                
                % 模拟缓存器访问
                for i = 1:length(addresses)
                    address = addresses(i);
                    
                    % 计算组索引和块索引
                    set_index = mod(floor(address / block_size), num_sets) + 1;
                    block_index = floor(address / (block_size * num_sets));
                    
                    % 检查缓存器是否命中
                    hit = false;
                    for j = 1:n
                        if cache(set_index, j) == block_index
                            hits = hits + 1;
                            hit = true;
                            break;
                        end
                    end
                    % 随机替换策略
                    % 如果未命中，则将当前块存入缓存器
                    if ~hit && (choice == 1)
                        % 随机选择一个位置存储当前块
                        index = randi(n);
                        cache(set_index, index) = block_index;
                    end
                    
                    if ~hit && (choice == 2)
                        % 找到 LRU 位置存储当前块
                        [~, index] = max(lru(set_index, :));
                        cache(set_index, index) = block_index;
                        lru(set_index, index) = 0;
                        for k = 1:n
                            if k ~= index && cache(set_index, k) > 0
                                lru(set_index, k) = lru(set_index, k) + 1;
                            end
                        end
                    end
                    
                    if ~hit && (choice == 3)
                        % 找到 FIFO 队列最老的位置存储当前块
                        index = fifo(set_index);
                        cache(set_index, index) = block_index;
                        fifo(set_index) = mod(index, n) + 1;
                    end
                end
                
                % 计算命中率
                hit_rate = hits / length(addresses);
           
            end
       
        end

        % Callback function
        function ContextMenuOpening(app, event)
            
            myButtonPushed(app, event)
            function myButtonPushed(app, event)
                % 创建一个新的窗口
                newFig = uifigure('Name', 'New Window');
                
                % 创建一个选项卡组
                tabGroup = uitabgroup(newFig);
                
                % 创建一个选项卡
                tab1 = uitab(tabGroup, 'Title', 'Tab 1');
                
                % 在选项卡中添加一个文本标签
                uilabel(tab1, 'Text', 'This is Tab 1');
            end
           
        end

        % Menu selected function: Menu2_2
        function Menu2_2Selected(app, event)
 
        end

        % Button pushed function: b2f
        function b2fButtonPushed(app, event)
           app.m = 0
           openFile();
           function openFile()
                [filename, pathname] = uigetfile({'*.txt', 'Text Files (*.txt)'}, 'Select a file');
                if isequal(filename, 0) || isequal(pathname, 0)
                      return ;
                end
                app.m = textread(filename,'%s');
                fullpath = fullfile(pathname, filename);
                 % 在此处添加打开文件的代码
            end
            
        end

        % Value changed function: tipsTextArea
        function tipsTextAreaValueChanged(app, event)
            value = app.tipsTextArea.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu_3 = uimenu(app.UIFigure);
            app.Menu_3.Text = 'Menu';
            app.Menu2_2 = uimenu(app.UIFigure);
            app.Menu2_2.MenuSelectedFcn = createCallbackFcn(app, @Menu2_2Selected, true);
            app.Menu2_2.Text = 'Menu2';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'O81@4IM_OCMVZSVR~SGZ7}Q.png';
            app.cacheLabel = uilabel(app.UIFigure);
            app.cacheLabel.Text = 'cache命中模拟器';
            app.botton_target_rate = uibutton(app.UIFigure, 'push');
            app.botton_target_rate.ButtonPushedFcn = createCallbackFcn(app, @botton_target_ratePushed, true);
            app.botton_target_rate.Text = '开始计算命中率';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '命中率变化曲线')
            xlabel(app.UIAxes, '自变量-默认为cache容量')
            ylabel(app.UIAxes, '命中率')
            app.cacheEditFieldLabel = uilabel(app.UIFigure);
            app.cacheEditFieldLabel.Text = 'cache容量';
            app.cache_size = uieditfield(app.UIFigure, 'numeric');
            app.cache_size.ValueChangedFcn = createCallbackFcn(app, @cache_sizeValueChanged, true);
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = '块大小';
            app.block_size = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '相连度';
            app.connection = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = '替换策略';
            app.strategy = uieditfield(app.UIFigure, 'numeric');
            app.tipsTextAreaLabel = uilabel(app.UIFigure);
            app.tipsTextAreaLabel.Text = 'tips';
            app.tipsTextArea = uitextarea(app.UIFigure);
            app.tipsTextArea.ValueChangedFcn = createCallbackFcn(app, @tipsTextAreaValueChanged, true);
            app.tipsTextArea.Value = {'1.cache容量一般为2的倍数'; '2.相连度为>=1的整数'; '3.块大小也为2的倍数'; '4.替换策略提供三种'; '分别是1 随机映射 2 LRU 3 FIFO'; ''};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '命中率';
            app.result = uieditfield(app.UIFigure, 'numeric');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '提供地址总数';
            app.temp1 = uieditfield(app.UIFigure, 'text');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '命中地址数';
            app.temp2 = uieditfield(app.UIFigure, 'text');
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '绘制命中率曲线';
            app.b1 = uiradiobutton(app.ButtonGroup);
            app.b1.Text = 'cache容量-命中率';
            app.b1.Value = true;
            app.b2 = uiradiobutton(app.ButtonGroup);
            app.b2.Text = '块大小-命中率';
            app.b3 = uiradiobutton(app.ButtonGroup);
            app.b3.Text = '相连度-命中率';
            app.b4 = uiradiobutton(app.ButtonGroup);
            app.b4.Text = '替换策略-命中率';
            app.b2f = uibutton(app.UIFigure, 'push');
            app.b2f.ButtonPushedFcn = createCallbackFcn(app, @b2fButtonPushed, true);
            app.b2f.Text = '导入需要的cache地址';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = '模拟器.svg';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = 'E2L3)})HLCUIIPI1PLX`4}8.png';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.UIFigure.ContextMenu = app.ContextMenu;
            app.Menu_4 = uimenu(app.ContextMenu);
            app.Menu_4.Text = 'Menu';
            app.Menu2_3 = uimenu(app.ContextMenu);
            app.Menu2_3.Text = 'Menu2';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = '科技.svg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cache_monitor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end